classdef canteen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label_8                  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_1                  matlab.ui.control.Label
        STARTButton              matlab.ui.control.Button
        Image1                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image8                   matlab.ui.control.Image
        Image7                   matlab.ui.control.Image
        Image6                   matlab.ui.control.Image
        Image5                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        CanteenGroupButtonGroup  matlab.ui.container.ButtonGroup
        Group3Button             matlab.ui.control.Button
        Group4Button             matlab.ui.control.Button
        Group2Button             matlab.ui.control.Button
        Group1Button             matlab.ui.control.Button
        CanteenListPanel         matlab.ui.container.Panel
        Group4ListBox            matlab.ui.control.ListBox
        Group4ListBoxLabel       matlab.ui.control.Label
        Group3ListBox            matlab.ui.control.ListBox
        Group3ListBoxLabel       matlab.ui.control.Label
        Group2ListBox            matlab.ui.control.ListBox
        Group2ListBoxLabel       matlab.ui.control.Label
        Group1ListBox            matlab.ui.control.ListBox
        Group1ListBoxLabel       matlab.ui.control.Label
        Image9                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            num = randi([23,30],1);
            final_t = 5;
            wait_time = linspace(0,final_t,num);
            Amp = 0.15;
            time_period = Amp*cos(2*pi/final_t*wait_time)+Amp+0.05;
            app.Image9.Position = [69,330,180,180];
            load('soud.mat');
            sound(x,floor(fs*25/num));
            for i=1:num
                pause(time_period(i));
                switch(mod(i,8))
                    case {1,2}
                        app.Image9.Position = app.Image9.Position+[156.5,0,0,0];
                    case {3,4} 
                        app.Image9.Position = app.Image9.Position+[0,-146.5,0,0];
                    case {5,6} 
                        app.Image9.Position = app.Image9.Position+[-156.5,0,0,0];
                    case {7,0} 
                        app.Image9.Position = app.Image9.Position+[0,146.5,0,0];
                    otherwise
                        fprintf('No move\n' );
                end
            end
        end

        % Button pushed function: Group1Button
        function Group1ButtonPushed(app, event)
            for i=1:8
                j = mod(i,4)+1;
                switch(i)
                    case 1  
                        app.Label_1.Text = app.Group1ListBox.Items(j);
                    case 2  
                        app.Label_2.Text = app.Group1ListBox.Items(j);
                    case 3  
                        app.Label_3.Text = app.Group1ListBox.Items(j);
                    case 4  
                        app.Label_4.Text = app.Group1ListBox.Items(j);
                    case 5  
                        app.Label_5.Text = app.Group1ListBox.Items(j);
                    case 6  
                        app.Label_6.Text = app.Group1ListBox.Items(j);
                    case 7  
                        app.Label_7.Text = app.Group1ListBox.Items(j);
                    case 8  
                        app.Label_8.Text = app.Group1ListBox.Items(j);
                    otherwise 
                        fprintf('No change!\n');
                end
            end
        end

        % Button pushed function: Group2Button
        function Group2ButtonPushed(app, event)
            for i=1:8
                j = mod(i,4)+1;
                switch(i)
                    case 1  
                        app.Label_1.Text = app.Group2ListBox.Items(j);
                    case 2  
                        app.Label_2.Text = app.Group2ListBox.Items(j);
                    case 3  
                        app.Label_3.Text = app.Group2ListBox.Items(j);
                    case 4  
                        app.Label_4.Text = app.Group2ListBox.Items(j);
                    case 5  
                        app.Label_5.Text = app.Group2ListBox.Items(j);
                    case 6  
                        app.Label_6.Text = app.Group2ListBox.Items(j);
                    case 7  
                        app.Label_7.Text = app.Group2ListBox.Items(j);
                    case 8  
                        app.Label_8.Text = app.Group2ListBox.Items(j);
                    otherwise 
                        fprintf('No change!\n');
                end
            end
        end

        % Button pushed function: Group4Button
        function Group4ButtonPushed(app, event)
            for i=1:8
                j = mod(i,4)+1;
                switch(i)
                    case 1  
                        app.Label_1.Text = app.Group4ListBox.Items(j);
                    case 2  
                        app.Label_2.Text = app.Group4ListBox.Items(j);
                    case 3  
                        app.Label_3.Text = app.Group4ListBox.Items(j);
                    case 4  
                        app.Label_4.Text = app.Group4ListBox.Items(j);
                    case 5  
                        app.Label_5.Text = app.Group4ListBox.Items(j);
                    case 6  
                        app.Label_6.Text = app.Group4ListBox.Items(j);
                    case 7  
                        app.Label_7.Text = app.Group4ListBox.Items(j);
                    case 8  
                        app.Label_8.Text = app.Group4ListBox.Items(j);
                    otherwise 
                        fprintf('No change!\n');
                end
            end
        end

        % Button pushed function: Group3Button
        function Group3ButtonPushed(app, event)
            for i=1:8
                j = mod(i,4)+1;
                switch(i)
                    case 1  
                        app.Label_1.Text = app.Group3ListBox.Items(j);
                    case 2  
                        app.Label_2.Text = app.Group3ListBox.Items(j);
                    case 3  
                        app.Label_3.Text = app.Group3ListBox.Items(j);
                    case 4  
                        app.Label_4.Text = app.Group3ListBox.Items(j);
                    case 5  
                        app.Label_5.Text = app.Group3ListBox.Items(j);
                    case 6  
                        app.Label_6.Text = app.Group3ListBox.Items(j);
                    case 7  
                        app.Label_7.Text = app.Group3ListBox.Items(j);
                    case 8  
                        app.Label_8.Text = app.Group3ListBox.Items(j);
                    otherwise 
                        fprintf('No change!\n');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = 'light.png';
            app.CanteenListPanel = uipanel(app.UIFigure);
            app.CanteenListPanel.Title = 'Canteen List';
            app.Group1ListBoxLabel = uilabel(app.CanteenListPanel);
            app.Group1ListBoxLabel.Text = 'Group1';
            app.Group1ListBox = uilistbox(app.CanteenListPanel);
            app.Group1ListBox.Items = {'清芬园', '听涛园', '观畴园', '丁香园'};
            app.Group1ListBox.Value = '清芬园';
            app.Group2ListBoxLabel = uilabel(app.CanteenListPanel);
            app.Group2ListBoxLabel.Text = 'Group2';
            app.Group2ListBox = uilistbox(app.CanteenListPanel);
            app.Group2ListBox.Items = {'清芬园', '听涛园', '观畴园', '南园  '};
            app.Group2ListBox.Value = '听涛园';
            app.Group3ListBoxLabel = uilabel(app.CanteenListPanel);
            app.Group3ListBoxLabel.Text = 'Group3';
            app.Group3ListBox = uilistbox(app.CanteenListPanel);
            app.Group3ListBox.Items = {'清芬园', '听涛园', '观畴园', '紫荆园'};
            app.Group3ListBox.Value = '听涛园';
            app.Group4ListBoxLabel = uilabel(app.CanteenListPanel);
            app.Group4ListBoxLabel.Text = 'Group4';
            app.Group4ListBox = uilistbox(app.CanteenListPanel);
            app.Group4ListBox.Items = {'清芬园', '听涛园', '观畴园', '照澜院'};
            app.Group4ListBox.Value = '听涛园';
            app.CanteenGroupButtonGroup = uibuttongroup(app.UIFigure);
            app.CanteenGroupButtonGroup.Title = 'Canteen Group';
            app.Group1Button = uibutton(app.CanteenGroupButtonGroup, 'push');
            app.Group1Button.ButtonPushedFcn = createCallbackFcn(app, @Group1ButtonPushed, true);
            app.Group1Button.Text = 'Group1';
            app.Group2Button = uibutton(app.CanteenGroupButtonGroup, 'push');
            app.Group2Button.ButtonPushedFcn = createCallbackFcn(app, @Group2ButtonPushed, true);
            app.Group2Button.Text = 'Group2';
            app.Group4Button = uibutton(app.CanteenGroupButtonGroup, 'push');
            app.Group4Button.ButtonPushedFcn = createCallbackFcn(app, @Group4ButtonPushed, true);
            app.Group4Button.Text = 'Group4';
            app.Group3Button = uibutton(app.CanteenGroupButtonGroup, 'push');
            app.Group3Button.ButtonPushedFcn = createCallbackFcn(app, @Group3ButtonPushed, true);
            app.Group3Button.Text = 'Group3';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '2.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = '4.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = '5.png';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = '6.png';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = '7.png';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = '8.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = '3.png';
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.ImageSource = '1.png';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Icon = 'te.png';
            app.STARTButton.Text = 'START';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Text = '清芬园';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '听涛园';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '观畴园';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '清芬园';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '丁香园';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '听涛园';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '清芬园';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '观畴园';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = canteen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end